classdef TSP < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                    matlab.ui.Figure
    TheTravelingSalespersonProblemTSPLabel  matlab.ui.control.Label
    ProbleminitializationPanel  matlab.ui.container.Panel
    GeneratepositionsButton     matlab.ui.control.Button
    CitiesSpinner               matlab.ui.control.Spinner
    CitiesSpinnerLabel          matlab.ui.control.Label
    ProblemsolutionPanel        matlab.ui.container.Panel
    SolveitButton               matlab.ui.control.Button
    AlgorithmDropDown           matlab.ui.control.DropDown
    AlgorithmDropDownLabel      matlab.ui.control.Label
    UIAxes                      matlab.ui.control.UIAxes
  end

  
  properties (Access = private)
    numberOfCities = 4    % Number of cities for the TSP
    cityArray = City.empty % Array of cities
    distanceMatrix = []    % Distance between pairs of cities
  end
  
  methods (Access = private)
    
    function execTime = solveExact(app)
      % start timer
      startTime = tic;

      % make local copies of variables
      N = app.numberOfCities;
      cities = app.cityArray;
      distance = app.distanceMatrix;

      % evaluate all possible routes (currently double of what is needed)
      routes = perms(2:N); 
      numberOfRoutes = factorial(N-1);
      routes = [ones(numberOfRoutes,1) routes() ones(numberOfRoutes,1)];
      minTotalDistance = inf;
      for route = routes'
        route = route';
        locations = [cities(route).location];
        plot(app.UIAxes, locations(1,1:N), locations(2,1:N), ...
          'ro', locations(1,:), locations(2,:), 'k-')
        labels = strsplit(num2str(route(1:N)));
        text(app.UIAxes, locations(1,1:end-1), locations(2,1:end-1), ...
          labels, 'VerticalAlignment', 'bottom', ...
          'HorizontalAlignment','right')
        totalDistance = 0;
        for idx = 1:N
          totalDistance = totalDistance + ...
            distance(route(idx), route(idx+1));
        end
        if totalDistance < minTotalDistance
          minTotalDistance = totalDistance;
          bestRoute = route;
        end
        drawnow
      end

      % plot best route
      route = bestRoute;
      locations = [cities(route).location];
      plot(app.UIAxes, locations(1,1:N), locations(2,1:N), ...
        'ro', locations(1,:), locations(2,:), 'k-')
      labels = strsplit(num2str(route(1:N)));
      text(app.UIAxes, locations(1,1:N), locations(2,1:N), ...
        labels, 'VerticalAlignment', 'bottom', ...
        'HorizontalAlignment','right')
      execTime = toc(startTime);
    end
    
    function execTime = solveClosestNeighbour(app)
      % start timer
      startTime = tic;

      % make local copies of variables
      N = app.numberOfCities;
      cities = app.cityArray;
      distance = app.distanceMatrix;

      % evaluate N possible routes (way less than (N-1)!)
      minTotalDistance = inf;
      for initialCity = 1:N
        route = initialCity;
        nextCityIndex = initialCity;
        totalDistance = 0;
        for idx = 1:N-1
          posibleDistance = distance(nextCityIndex,:);
          posibleDistance(route) = inf;
          [minDistance, nextCityIndex] = min(posibleDistance);
          totalDistance = totalDistance + minDistance;
          route = [route nextCityIndex];
        end
        route = [route initialCity];
        totalDistance = totalDistance + ...
          distance(route(end-1),route(end));
        if totalDistance < minTotalDistance
          minTotalDistance = totalDistance;
          bestRoute = route;
        end
        locations = [cities(route).location];
        plot(app.UIAxes, locations(1,1:N), locations(2,1:N), ...
          'ro', locations(1,:), locations(2,:), 'k-')
        labels = strsplit(num2str(route(1:N)));
        text(app.UIAxes, locations(1,1:N), locations(2,1:N), labels, ...
          'VerticalAlignment', 'bottom', 'HorizontalAlignment','right')
        drawnow
      end
      route = bestRoute;
      locations = [cities(route).location];
      plot(app.UIAxes, locations(1,1:N), locations(2,1:N), 'ro', ...
        locations(1,:), locations(2,:), 'k-')
      labels = strsplit(num2str(route(1:end-1)));
      text(app.UIAxes, locations(1,1:N), locations(2,1:N), labels, ...
        'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right')
      execTime = toc(startTime);
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Value changed function: CitiesSpinner
    function CitiesSpinnerValueChanged(app, event)
      value = app.CitiesSpinner.Value;
      app.numberOfCities = value;
    end

    % Button pushed function: GeneratepositionsButton
    function GeneratepositionsButtonPushed(app, event)
      % create random cities and evaluate distance matrix
      cities = City.empty;
      distances = zeros(app.numberOfCities);
      for idx=1:app.numberOfCities
        cities(idx) = City(rand,rand);
        for jdx=idx-1:-1:1
          distances(idx,jdx) = ...
            sqrt(sum((cities(idx).location-cities(jdx).location).^2));
          distances(jdx,idx) = distances(idx,jdx);
        end
      end
      app.cityArray = cities;
      app.distanceMatrix = distances;

      % plot position of cities in the graph
      locations = [cities(1:app.numberOfCities).location];
      plot(app.UIAxes, locations(1,1:end), locations(2,1:end), 'ro')
      labels = strsplit(num2str(1:app.numberOfCities));
      text(app.UIAxes, locations(1,1:end), locations(2,1:end), ...
        labels, 'VerticalAlignment', 'bottom', ...
        'HorizontalAlignment','right')

      % activate "Solve it!" button (conditions apply)
      if ( strcmp(app.AlgorithmDropDown.Value, "Exact") && ...
          app.numberOfCities<=10 ) || ...
          strcmp(app.AlgorithmDropDown.Value, "Closest neighbour")
        app.SolveitButton.Enable = true;
      else
        app.SolveitButton.Enable = false;
      end

    end

    % Button pushed function: SolveitButton
    function SolveitButtonPushed(app, event)
      switch app.AlgorithmDropDown.Value
        case 'Exact'
          app.solveExact();
        case 'Closest neighbour'
          app.solveClosestNeighbour();
      end
    end

    % Value changed function: AlgorithmDropDown
    function AlgorithmDropDownValueChanged(app, event)
      value = app.AlgorithmDropDown.Value;
      
      % activate "Solve it!" button (conditions apply)
      if ~isempty(app.cityArray)
        if ( strcmp(value, "Exact") && app.numberOfCities<=10 ) || ...
            strcmp(value, "Closest neighbour")
          app.SolveitButton.Enable = true;
        else
          app.SolveitButton.Enable = false;
        end
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIAxes = uiaxes(app.UIFigure);
      title(app.UIAxes, 'Map of the cities')
      xlabel(app.UIAxes, 'X position')
      ylabel(app.UIAxes, 'Y position')
      zlabel(app.UIAxes, 'Z')
      app.UIAxes.XLim = [0 1];
      app.UIAxes.YLim = [0 1];
      app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
      app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
      app.ProblemsolutionPanel = uipanel(app.UIFigure);
      app.ProblemsolutionPanel.Title = 'Problem solution';
      app.AlgorithmDropDownLabel = uilabel(app.ProblemsolutionPanel);
      app.AlgorithmDropDownLabel.Text = 'Algorithm';
      app.AlgorithmDropDown = uidropdown(app.ProblemsolutionPanel);
      app.AlgorithmDropDown.Items = {'Exact', 'Closest neighbour'};
      app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
      app.AlgorithmDropDown.Value = 'Exact';
      app.SolveitButton = uibutton(app.ProblemsolutionPanel, 'push');
      app.SolveitButton.ButtonPushedFcn = createCallbackFcn(app, @SolveitButtonPushed, true);
      app.SolveitButton.Enable = 'off';
      app.SolveitButton.Text = 'Solve it!';
      app.ProbleminitializationPanel = uipanel(app.UIFigure);
      app.ProbleminitializationPanel.Title = 'Problem initialization';
      app.CitiesSpinnerLabel = uilabel(app.ProbleminitializationPanel);
      app.CitiesSpinnerLabel.Text = '# Cities';
      app.CitiesSpinner = uispinner(app.ProbleminitializationPanel);
      app.CitiesSpinner.Limits = [4 Inf];
      app.CitiesSpinner.ValueChangedFcn = createCallbackFcn(app, @CitiesSpinnerValueChanged, true);
      app.CitiesSpinner.Value = 4;
      app.GeneratepositionsButton = uibutton(app.ProbleminitializationPanel, 'push');
      app.GeneratepositionsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratepositionsButtonPushed, true);
      app.GeneratepositionsButton.Text = 'Generate positions';
      app.TheTravelingSalespersonProblemTSPLabel = uilabel(app.UIFigure);
      app.TheTravelingSalespersonProblemTSPLabel.Text = 'The Traveling Salesperson Problem (TSP)';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = TSP

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end